classdef scgeatoolApp < matlab.apps.AppBase
    %SCGEATOOLAPP App of scgeatool
    %   Single-cell Gene Expression Analysis Tool App

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportDataMenu                  matlab.ui.container.Menu
        ExportSaveDataMenu              matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        FilterGenesCellsMenu            matlab.ui.container.Menu
        AddBrushedCellstoaNewGroupMenu  matlab.ui.container.Menu
        MergeBrushedCellstoSameGroupMenu  matlab.ui.container.Menu
        RenameCellTypeorBatchIDMenu     matlab.ui.container.Menu
        MergeSCEDataVariablesinWorkspaceMenu  matlab.ui.container.Menu
        MergeSCEDataFilesMenu           matlab.ui.container.Menu
        AddEditCellAttributesMenu       matlab.ui.container.Menu
        ExportCellAttributeTableMenu    matlab.ui.container.Menu
        ExtractCellsbyMarkerExpressionMenu  matlab.ui.container.Menu
        MergeSubclustersofSameCellTypeMenu  matlab.ui.container.Menu
        SelectGenestoWorkonMenu         matlab.ui.container.Menu
        SelectHighlyVariableGenesHVGstoWorkonMenu  matlab.ui.container.Menu
        SelectLigandReceptorGenestoWorkonMenu  matlab.ui.container.Menu
        Subsample50CellstoWorkonMenu    matlab.ui.container.Menu
        DeleteBrushedCellsMenu          matlab.ui.container.Menu
        DeleteUnbrushedCellsMenu        matlab.ui.container.Menu
        SelectCellsbyClassOpeninNewWindowMenu  matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
        Menu4                           matlab.ui.container.Menu
        Menu5                           matlab.ui.container.Menu
        Menu6                           matlab.ui.container.Menu
        Menu7                           matlab.ui.container.Menu
        Menu8                           matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
        Menu3_2                         matlab.ui.container.Menu
        Menu4_2                         matlab.ui.container.Menu
        Menu5_2                         matlab.ui.container.Menu
        Menu6_2                         matlab.ui.container.Menu
        Menu7_2                         matlab.ui.container.Menu
        AnnotateMenu                    matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        Menu2_3                         matlab.ui.container.Menu
        Menu3_3                         matlab.ui.container.Menu
        Menu4_3                         matlab.ui.container.Menu
        Menu5_3                         matlab.ui.container.Menu
        Menu6_3                         matlab.ui.container.Menu
        Menu7_3                         matlab.ui.container.Menu
        AnalyzeMenu                     matlab.ui.container.Menu
        Menu_4                          matlab.ui.container.Menu
        Menu2_4                         matlab.ui.container.Menu
        Menu3_4                         matlab.ui.container.Menu
        Menu4_4                         matlab.ui.container.Menu
        Menu5_4                         matlab.ui.container.Menu
        Menu6_4                         matlab.ui.container.Menu
        Menu7_4                         matlab.ui.container.Menu
        NetworkMenu                     matlab.ui.container.Menu
        Menu_5                          matlab.ui.container.Menu
        Menu2_5                         matlab.ui.container.Menu
        Menu3_5                         matlab.ui.container.Menu
        Menu4_5                         matlab.ui.container.Menu
        ExternalMenu                    matlab.ui.container.Menu
        SetWorkingFolderMenu            matlab.ui.container.Menu
        Menu2_6                         matlab.ui.container.Menu
        Menu3_6                         matlab.ui.container.Menu
        Menu4_6                         matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ViewscGEAToolboxonFileExchangeMenu  matlab.ui.container.Menu
        AboutSCGEATOOLMenu              matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool_ImportData             matlab.ui.container.toolbar.PushTool
        PushTool_MultigroupingView      matlab.ui.container.toolbar.PushTool
        PushTool_CrossTabulation        matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool19                      matlab.ui.container.toolbar.PushTool
        PushTool20                      matlab.ui.container.toolbar.PushTool
        PushTool21                      matlab.ui.container.toolbar.PushTool
        PushTool22                      matlab.ui.container.toolbar.PushTool
        PushTool23                      matlab.ui.container.toolbar.PushTool
        PushTool24                      matlab.ui.container.toolbar.PushTool
        PushTool25                      matlab.ui.container.toolbar.PushTool
        PushTool26                      matlab.ui.container.toolbar.PushTool
        PushTool27                      matlab.ui.container.toolbar.PushTool
        PushTool28                      matlab.ui.container.toolbar.PushTool
        PushTool29                      matlab.ui.container.toolbar.PushTool
        PushTool30                      matlab.ui.container.toolbar.PushTool
        Toolbar2                        matlab.ui.container.Toolbar
        PushTool_ShowGeneExpr           matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        PushTool8                       matlab.ui.container.toolbar.PushTool
        PushTool10                      matlab.ui.container.toolbar.PushTool
        PushTool9                       matlab.ui.container.toolbar.PushTool
        PushTool11                      matlab.ui.container.toolbar.PushTool
        PushTool12                      matlab.ui.container.toolbar.PushTool
        PushTool13                      matlab.ui.container.toolbar.PushTool
        PushTool14                      matlab.ui.container.toolbar.PushTool
        PushTool15                      matlab.ui.container.toolbar.PushTool
        PushTool16                      matlab.ui.container.toolbar.PushTool
        PushTool17                      matlab.ui.container.toolbar.PushTool
        PushTool18                      matlab.ui.container.toolbar.PushTool
        PushTool_SaveX                  matlab.ui.container.toolbar.PushTool
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)        
        
    end
    
    properties (Access = public)
        sce = SingleCellExperiment;
    end
    
    methods (Access = private)
        
        function results = in_UpdateMainPlot(app)
            in_UpdatePushToolMenu(app);
            if app.sce.NumCells == 0
                app.UIAxes.Visible = "off";
            else
                results = gui.i_gscatter3(app.sce.s, app.sce.c, 1, [], app.UIAxes);
                title(app.UIAxes, app.sce.title);
                subtitle(app.UIAxes, '[genes x cells]');
                app.UIAxes.Visible = "on";
            end
        end
        
        function in_UpdatePushToolMenu(app)
            pushTools = i_GetConditionOnPushTool(app);
            menuItems = i_GetConditionOnMenu(app);
            tag = app.sce.NumCells ~= 0;
            set(pushTools, 'Enable', tag);
            set(menuItems, 'Enable', tag);
        end
        
        function results = i_GetAlwaysOnPushTool(app)
            results = [app.PushTool_ImportData];
        end
        
        function results = i_GetConditionOnPushTool(app)
            pushTools1 = i_GetAlwaysOnPushTool(app);
            pushTools2 = findall(app.UIFigure, 'Type', 'uipushtool');
            results = setdiff(pushTools2, pushTools1);
        end
        
        function results = i_GetAlwaysOnMenu(app)
            results = [app.FileMenu; app.EditMenu; app.ViewMenu; ...
                app.AnnotateMenu; app.AnalyzeMenu; app.PlotsMenu; ...
                app.NetworkMenu; app.ExternalMenu; ...
                app.HelpMenu; app.SetWorkingFolderMenu; ...
                app.ImportDataMenu; app.CloseMenu; ...
                app.ViewscGEAToolboxonFileExchangeMenu; ...
                app.AboutSCGEATOOLMenu];
        end
        
        function results = i_GetConditionOnMenu(app)
            menuItems1 = i_GetAlwaysOnMenu(app);
            menuItems2 = findall(app.UIFigure, 'Type', 'uimenu');
            results = setdiff(menuItems2, menuItems1);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            v1 = pkg.i_get_versionnum;
            app.UIFigure.Name = "SCGEATOOL v" + v1;
            in_UpdatePushToolMenu(app);
            app.UIAxes.Visible = "off";
            movegui(app.UIFigure, 'center')
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.delete;
        end

        % Callback function: ImportDataMenu, PushTool_ImportData
        function PushTool_ImportDataClicked(app, event)
            [res] = gui.sc_openscedlg([], [], app.UIFigure);
            if ~isempty(res)
                app.sce = res;                
            end
            app.in_UpdateMainPlot;
        end

        % Callback function: PushTool_ShowGeneExpr
        function PushTool_ShowGeneExprClicked(app, event)
            xui.callback_ShowGeneExpr(app, event);
        end

        % Callback function: ExportSaveDataMenu, PushTool_SaveX
        function PushTool_SaveXClicked(app, event)
            gui.i_isuifig(app.UIFigure)
            xui.callback_SaveX(app, event);
        end

        % Callback function: PushTool_MultigroupingView
        function PushTool_MultigroupingViewClicked(app, event)
            xui.callback_MultiGroupingView(app, event);
        end

        % Callback function: PushTool_CrossTabulation
        function PushTool_CrossTabulationClicked(app, event)
            xui.callback_CrossTabulation(app, event);
        end

        % Menu selected function: SetWorkingFolderMenu
        function SetWorkingFolderMenuSelected(app, event)
            [done] = gui.i_setwrkdir('externalwrkpath');
            if done
                 gui.myHelpdlg(app.UIFigure, "External program working " + ...
                     "directory is set successfully.");
            end
        end

        % Menu selected function: AboutSCGEATOOLMenu
        function AboutSCGEATOOLMenuSelected(app, event)
                [~, ~, ~, im] = pkg.i_majvercheck([false false false true]);
                gui.sc_simpleabout(app.UIFigure, im);                
        end

        % Menu selected function: ViewscGEAToolboxonFileExchangeMenu
        function ViewscGEAToolboxonFileExchangeMenuSelected(app, event)
            web('https://www.mathworks.com/matlabcentral/fileexchange/72917-scgeatoolbox-single-cell-gene-expression-analysis-toolbox');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCGEATOOL APP';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'app', 'scgeatoolApp_resources', 'icon_16.png');
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.ImportDataMenu.Accelerator = 'i';
            app.ImportDataMenu.Text = '&Import Data...';
            app.ExportSaveDataMenu = uimenu(app.FileMenu);
            app.ExportSaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.ExportSaveDataMenu.Accelerator = 's';
            app.ExportSaveDataMenu.Text = 'Export/Save Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Accelerator = 'w';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = '&Edit';
            app.FilterGenesCellsMenu = uimenu(app.EditMenu);
            app.FilterGenesCellsMenu.Accelerator = 'f';
            app.FilterGenesCellsMenu.Text = '&Filter Genes & Cells...';
            app.AddBrushedCellstoaNewGroupMenu = uimenu(app.EditMenu);
            app.AddBrushedCellstoaNewGroupMenu.Separator = 'on';
            app.AddBrushedCellstoaNewGroupMenu.Text = 'Add Brushed Cells to a New Group';
            app.MergeBrushedCellstoSameGroupMenu = uimenu(app.EditMenu);
            app.MergeBrushedCellstoSameGroupMenu.Text = 'Merge Brushed Cells to Same Group';
            app.RenameCellTypeorBatchIDMenu = uimenu(app.EditMenu);
            app.RenameCellTypeorBatchIDMenu.Text = 'Rename Cell Type or Batch ID...';
            app.MergeSCEDataVariablesinWorkspaceMenu = uimenu(app.EditMenu);
            app.MergeSCEDataVariablesinWorkspaceMenu.Separator = 'on';
            app.MergeSCEDataVariablesinWorkspaceMenu.Text = 'Merge SCE Data Variables in Workspace...';
            app.MergeSCEDataFilesMenu = uimenu(app.EditMenu);
            app.MergeSCEDataFilesMenu.Text = 'Merge SCE Data Files...';
            app.AddEditCellAttributesMenu = uimenu(app.EditMenu);
            app.AddEditCellAttributesMenu.Separator = 'on';
            app.AddEditCellAttributesMenu.Text = 'Add/Edit Cell Attributes...';
            app.ExportCellAttributeTableMenu = uimenu(app.EditMenu);
            app.ExportCellAttributeTableMenu.Text = 'Export Cell Attribute Table...';
            app.ExtractCellsbyMarkerExpressionMenu = uimenu(app.EditMenu);
            app.ExtractCellsbyMarkerExpressionMenu.Separator = 'on';
            app.ExtractCellsbyMarkerExpressionMenu.Text = 'Extract Cells by Marker (+/-) Expression...';
            app.MergeSubclustersofSameCellTypeMenu = uimenu(app.EditMenu);
            app.MergeSubclustersofSameCellTypeMenu.Text = 'Merge Subclusters of Same Cell Type';
            app.SelectGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectGenestoWorkonMenu.Separator = 'on';
            app.SelectGenestoWorkonMenu.Text = 'Select Genes to Work on...';
            app.SelectHighlyVariableGenesHVGstoWorkonMenu = uimenu(app.EditMenu);
            app.SelectHighlyVariableGenesHVGstoWorkonMenu.Text = 'Select Highly Variable Genes (HVGs) to Work on...';
            app.SelectLigandReceptorGenestoWorkonMenu = uimenu(app.EditMenu);
            app.SelectLigandReceptorGenestoWorkonMenu.Text = 'Select Ligand Receptor Genes to Work on...';
            app.Subsample50CellstoWorkonMenu = uimenu(app.EditMenu);
            app.Subsample50CellstoWorkonMenu.Text = 'Subsample 50% Cells to Work on...';
            app.DeleteBrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteBrushedCellsMenu.Separator = 'on';
            app.DeleteBrushedCellsMenu.Text = 'Delete Brushed Cells...';
            app.DeleteUnbrushedCellsMenu = uimenu(app.EditMenu);
            app.DeleteUnbrushedCellsMenu.Text = 'Delete Unbrushed Cells...';
            app.SelectCellsbyClassOpeninNewWindowMenu = uimenu(app.EditMenu);
            app.SelectCellsbyClassOpeninNewWindowMenu.Separator = 'on';
            app.SelectCellsbyClassOpeninNewWindowMenu.Text = 'Select Cells by Class & Open in New Window...';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.Menu = uimenu(app.ViewMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ViewMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.ViewMenu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.ViewMenu);
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.ViewMenu);
            app.Menu5.Text = 'Menu5';
            app.Menu6 = uimenu(app.ViewMenu);
            app.Menu6.Text = 'Menu6';
            app.Menu7 = uimenu(app.ViewMenu);
            app.Menu7.Text = 'Menu7';
            app.Menu8 = uimenu(app.ViewMenu);
            app.Menu8.Text = 'Menu8';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.Menu_2 = uimenu(app.PlotsMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.PlotsMenu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.PlotsMenu);
            app.Menu3_2.Text = 'Menu3';
            app.Menu4_2 = uimenu(app.PlotsMenu);
            app.Menu4_2.Text = 'Menu4';
            app.Menu5_2 = uimenu(app.PlotsMenu);
            app.Menu5_2.Text = 'Menu5';
            app.Menu6_2 = uimenu(app.PlotsMenu);
            app.Menu6_2.Text = 'Menu6';
            app.Menu7_2 = uimenu(app.PlotsMenu);
            app.Menu7_2.Text = 'Menu7';
            app.AnnotateMenu = uimenu(app.UIFigure);
            app.AnnotateMenu.Text = 'Annotate';
            app.Menu_3 = uimenu(app.AnnotateMenu);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.AnnotateMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_3 = uimenu(app.AnnotateMenu);
            app.Menu3_3.Text = 'Menu3';
            app.Menu4_3 = uimenu(app.AnnotateMenu);
            app.Menu4_3.Text = 'Menu4';
            app.Menu5_3 = uimenu(app.AnnotateMenu);
            app.Menu5_3.Text = 'Menu5';
            app.Menu6_3 = uimenu(app.AnnotateMenu);
            app.Menu6_3.Text = 'Menu6';
            app.Menu7_3 = uimenu(app.AnnotateMenu);
            app.Menu7_3.Text = 'Menu7';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.Menu_4 = uimenu(app.AnalyzeMenu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.AnalyzeMenu);
            app.Menu2_4.Text = 'Menu2';
            app.Menu3_4 = uimenu(app.AnalyzeMenu);
            app.Menu3_4.Text = 'Menu3';
            app.Menu4_4 = uimenu(app.AnalyzeMenu);
            app.Menu4_4.Text = 'Menu4';
            app.Menu5_4 = uimenu(app.AnalyzeMenu);
            app.Menu5_4.Text = 'Menu5';
            app.Menu6_4 = uimenu(app.AnalyzeMenu);
            app.Menu6_4.Text = 'Menu6';
            app.Menu7_4 = uimenu(app.AnalyzeMenu);
            app.Menu7_4.Text = 'Menu7';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.Menu_5 = uimenu(app.NetworkMenu);
            app.Menu_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.NetworkMenu);
            app.Menu2_5.Text = 'Menu2';
            app.Menu3_5 = uimenu(app.NetworkMenu);
            app.Menu3_5.Text = 'Menu3';
            app.Menu4_5 = uimenu(app.NetworkMenu);
            app.Menu4_5.Text = 'Menu4';
            app.ExternalMenu = uimenu(app.UIFigure);
            app.ExternalMenu.Text = 'External';
            app.SetWorkingFolderMenu = uimenu(app.ExternalMenu);
            app.SetWorkingFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetWorkingFolderMenuSelected, true);
            app.SetWorkingFolderMenu.Text = 'Set Working Folder...';
            app.Menu2_6 = uimenu(app.ExternalMenu);
            app.Menu2_6.Text = 'Menu2';
            app.Menu3_6 = uimenu(app.ExternalMenu);
            app.Menu3_6.Text = 'Menu3';
            app.Menu4_6 = uimenu(app.ExternalMenu);
            app.Menu4_6.Text = 'Menu4';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ViewscGEAToolboxonFileExchangeMenu = uimenu(app.HelpMenu);
            app.ViewscGEAToolboxonFileExchangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewscGEAToolboxonFileExchangeMenuSelected, true);
            app.ViewscGEAToolboxonFileExchangeMenu.Text = 'View scGEAToolbox on File Exchange...';
            app.AboutSCGEATOOLMenu = uimenu(app.HelpMenu);
            app.AboutSCGEATOOLMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSCGEATOOLMenuSelected, true);
            app.AboutSCGEATOOLMenu.Text = 'About SCGEATOOL';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_ImportData = uipushtool(app.Toolbar);
            app.PushTool_ImportData.Tooltip = {'Import Data...'};
            app.PushTool_ImportData.ClickedCallback = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.PushTool_ImportData.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'box3d-center.jpg');
            app.PushTool_MultigroupingView = uipushtool(app.Toolbar);
            app.PushTool_MultigroupingView.Tooltip = {'Multi-grouping View...'};
            app.PushTool_MultigroupingView.ClickedCallback = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.PushTool_MultigroupingView.Enable = 'off';
            app.PushTool_MultigroupingView.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'visibility_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_CrossTabulation = uipushtool(app.Toolbar);
            app.PushTool_CrossTabulation.Tooltip = {'Cross tabulation...'};
            app.PushTool_CrossTabulation.ClickedCallback = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.PushTool_CrossTabulation.Enable = 'off';
            app.PushTool_CrossTabulation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'full_stacked_bar_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Separator = 'on';
            app.PushTool19 = uipushtool(app.Toolbar);
            app.PushTool20 = uipushtool(app.Toolbar);
            app.PushTool21 = uipushtool(app.Toolbar);
            app.PushTool22 = uipushtool(app.Toolbar);
            app.PushTool23 = uipushtool(app.Toolbar);
            app.PushTool24 = uipushtool(app.Toolbar);
            app.PushTool25 = uipushtool(app.Toolbar);
            app.PushTool26 = uipushtool(app.Toolbar);
            app.PushTool27 = uipushtool(app.Toolbar);
            app.PushTool28 = uipushtool(app.Toolbar);
            app.PushTool29 = uipushtool(app.Toolbar);
            app.PushTool30 = uipushtool(app.Toolbar);
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool_ShowGeneExpr = uipushtool(app.Toolbar2);
            app.PushTool_ShowGeneExpr.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.PushTool_ShowGeneExpr.Enable = 'off';
            app.PushTool_ShowGeneExpr.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'google-docs.jpg');
            app.PushTool6 = uipushtool(app.Toolbar2);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'bookmark-book.jpg');
            app.PushTool7 = uipushtool(app.Toolbar2);
            app.PushTool7.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'filter-alt.jpg');
            app.PushTool8 = uipushtool(app.Toolbar2);
            app.PushTool8.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'label.jpg');
            app.PushTool8.Separator = 'on';
            app.PushTool10 = uipushtool(app.Toolbar2);
            app.PushTool10.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'substract.jpg');
            app.PushTool9 = uipushtool(app.Toolbar2);
            app.PushTool9.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'union.jpg');
            app.PushTool11 = uipushtool(app.Toolbar2);
            app.PushTool11.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'edit.jpg');
            app.PushTool12 = uipushtool(app.Toolbar2);
            app.PushTool12.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'apple-swift.jpg');
            app.PushTool13 = uipushtool(app.Toolbar2);
            app.PushTool13.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'bubble_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool14 = uipushtool(app.Toolbar2);
            app.PushTool14.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'auto_transmission_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool15 = uipushtool(app.Toolbar2);
            app.PushTool15.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'color-picker.jpg');
            app.PushTool16 = uipushtool(app.Toolbar2);
            app.PushTool16.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'medal1st.jpg');
            app.PushTool17 = uipushtool(app.Toolbar2);
            app.PushTool17.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'grain_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool17.Separator = 'on';
            app.PushTool18 = uipushtool(app.Toolbar2);
            app.PushTool18.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'view-grid.jpg');
            app.PushTool_SaveX = uipushtool(app.Toolbar2);
            app.PushTool_SaveX.ClickedCallback = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.PushTool_SaveX.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'floppy-disk.jpg');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end